classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        detectEmotionButton    matlab.ui.control.Button
        extractFeaturesButton  matlab.ui.control.Button
        preprocessWavButton    matlab.ui.control.Button
        importWavButton        matlab.ui.control.Button
        importModelButton      matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelFilePath % 模型文件路径属性
        wavFilePath   % WAV文件路径属性
        rf_model % 随机森林模型属性
        y        % WAV文件内容属性
        Fs
        mfccs
        pitch_values
        rms_values
        duration_value
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importModelButton
        function importModelButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select the Model');
            app.modelFilePath = fullfile(pathname, filename);
            % 从结构体中提取正确的模型对象
            modelStruct = load(app.modelFilePath);
            app.rf_model = modelStruct.rf_model;
        end

        % Button pushed function: importWavButton
        function importWavButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.wav', 'Select the WAV File');
            app.wavFilePath = fullfile(pathname, filename);
            [app.y, app.Fs] = audioread(app.wavFilePath);
            plot(app.UIAxes, app.y);
        end

        % Button pushed function: preprocessWavButton
        function preprocessWavButtonPushed(app, event)
            % 重采样
            target_fs = 16000;
            app.y = resample(app.y, target_fs, app.Fs);
            app.Fs = target_fs;

            % 转换为单声道
            if size(app.y, 2) > 1
                app.y = mean(app.y, 2);
            end

            % 绘制波形
            plot(app.UIAxes2, app.y);
        end

        % Button pushed function: extractFeaturesButton
        function extractFeaturesButtonPushed(app, event)
            % 提取MFCC特征
            app.mfccs = mfcc(app.y, app.Fs, 'LogEnergy', 'Replace');

            % 提取音高特征
            app.pitch_values = pitch(app.y, app.Fs);
            app.pitch_values = repmat(mean(app.pitch_values), size(app.mfccs, 1), 1);

            % 计算音强特征
            frame_length = 0.025;
            frame_overlap = 0.01;
            frame_length_samples = round(frame_length * app.Fs);
            frame_overlap_samples = round(frame_overlap * app.Fs);
            rms_window = hamming(frame_length_samples, 'periodic');
            frame_starts = 1:frame_overlap_samples:length(app.y)-frame_length_samples+1;
            app.rms_values = zeros(length(frame_starts), 1);
            for i = 1:length(frame_starts)
                frame = app.y(frame_starts(i):frame_starts(i)+frame_length_samples-1);
                app.rms_values(i) = sqrt(mean(frame.^2));
            end
            app.rms_values = app.rms_values(1:size(app.mfccs, 1), :);

            % 提取持续时间特征
            app.duration_value = length(app.y) / app.Fs;
        end

        % Button pushed function: detectEmotionButton
        function detectEmotionButtonPushed(app, event)
            % 获取文件名（不含路径）
            [~, modelName, ~] = fileparts(app.modelFilePath);

            % 根据导入的模型确定使用哪个特征
            switch modelName
                case 'rf_model_Pitch'
                    features = app.pitch_values;
                case 'rf_model_Timbre (MFCC)'
                    features = app.mfccs;
                case 'rf_model_Loudness (RMS)'
                    features = app.rms_values;
                case 'rf_model_Duration'
                    features = app.duration_value;
                otherwise
                    error('Invalid model file.');
            end

            % 使用加载的随机森林模型进行情感预测
            predicted_labels = str2double(predict(app.rf_model, features));
            predicted_label = mode(predicted_labels);
            emotions = {'anger', 'disgust', 'fear', 'happiness', 'sadness', 'surprise'};
            predicted_emotions = emotions(predicted_label);  % 提取相应的字符串
            predicted_emotions_str = strjoin(predicted_emotions, ', ');  % 将多个字符串连接为一个字符串
            msgbox(predicted_emotions_str, '分类结果');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始音频波形')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '预处理波形')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.importModelButton = uibutton(app.UIFigure, 'push');
            app.importModelButton.ButtonPushedFcn = createCallbackFcn(app, @importModelButtonPushed, true);
            app.importModelButton.Text = '导入模型';
            app.importWavButton = uibutton(app.UIFigure, 'push');
            app.importWavButton.ButtonPushedFcn = createCallbackFcn(app, @importWavButtonPushed, true);
            app.importWavButton.Text = '导入待测音频';
            app.preprocessWavButton = uibutton(app.UIFigure, 'push');
            app.preprocessWavButton.ButtonPushedFcn = createCallbackFcn(app, @preprocessWavButtonPushed, true);
            app.preprocessWavButton.Text = '预处理音频';
            app.extractFeaturesButton = uibutton(app.UIFigure, 'push');
            app.extractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @extractFeaturesButtonPushed, true);
            app.extractFeaturesButton.Text = '提取特征';
            app.detectEmotionButton = uibutton(app.UIFigure, 'push');
            app.detectEmotionButton.ButtonPushedFcn = createCallbackFcn(app, @detectEmotionButtonPushed, true);
            app.detectEmotionButton.Text = '检测情感';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end